classdef Guardian_Battery_Core < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        TimeScope2_4        matlab.ui.scope.TimeScope
        TimeScope2_3        matlab.ui.scope.TimeScope
        TimeScope2_2        matlab.ui.scope.TimeScope
        TimeScope2          matlab.ui.scope.TimeScope
        SimulationProgress  simulink.ui.control.SimulationProgress
        TimeScope           matlab.ui.scope.TimeScope
        SimulationControls  simulink.ui.control.SimulationControls
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SimulationControls = uisimcontrols(app.UIFigure);
            app.SimulationControls.Simulation = app.Simulation;
            app.TimeScope = uitimescope(app.UIFigure);
            app.TimeScope.LegendNames = {'Guardian_Battery_core/From2:1'};
            app.TimeScope.Title = 'V1_dis';
            app.TimeScope.YLabel = 'Amplitude';
            app.TimeScope.YLimits = [-0.001 0.001];
            app.TimeScope.YLimitsMethod = 'manual';
            app.SimulationProgress = uisimprogress(app.UIFigure);
            app.SimulationProgress.Simulation = app.Simulation;
            app.TimeScope2 = uitimescope(app.UIFigure);
            app.TimeScope2.LegendNames = {'Guardian_Battery_core/From3:1'};
            app.TimeScope2.Title = 'V0_dis';
            app.TimeScope2.YLabel = 'Amplitude';
            app.TimeScope2.YLimits = [-0.001 0.001];
            app.TimeScope2.YLimitsMethod = 'manual';
            app.TimeScope2_2 = uitimescope(app.UIFigure);
            app.TimeScope2_2.LegendNames = {'Guardian_Battery_core/From4:1'};
            app.TimeScope2_2.Title = 'SOC_dis';
            app.TimeScope2_2.YLabel = 'Amplitude';
            app.TimeScope2_2.YLimits = [0 80];
            app.TimeScope2_2.YLimitsMethod = 'manual';
            app.TimeScope2_3 = uitimescope(app.UIFigure);
            app.TimeScope2_3.LegendNames = {'Guardian_Battery_core/From3:1'};
            app.TimeScope2_3.Title = 'V_terminal_dis';
            app.TimeScope2_3.YLabel = 'Amplitude';
            app.TimeScope2_3.YLimits = [0 5];
            app.TimeScope2_3.YLimitsMethod = 'manual';
            app.TimeScope2_4 = uitimescope(app.UIFigure);
            app.TimeScope2_4.LegendNames = {'Guardian_Battery_core/From3:1'};
            app.TimeScope2_4.Title = 'V2_dis';
            app.TimeScope2_4.YLabel = 'Amplitude';
            app.TimeScope2_4.YLimits = [-0.001 0.001];
            app.TimeScope2_4.YLimitsMethod = 'manual';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % Bind initialization
    methods (Access = protected)

        function createBindings(app)

            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/From:1'], app.TimeScope2_3);
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/Guardian_Battery_core/From2:1'], app.TimeScope);
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/Guardian_Battery_core/From3:1'], app.TimeScope2);
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/Guardian_Battery_core/From4:1'], app.TimeScope2_2);
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/Guardian_Battery_core/From6:1'], app.TimeScope2_4);
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Guardian_Battery_Core

            % Associate the Simulink Model
            app.Simulation = simulation('Guardian_Battery_ECM');

            % Create UIFigure and components
            createComponents(app)

            % Create bindings
            createBindings(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end